classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        ExportmelodyMenu               matlab.ui.container.Menu
        WAVMenu                        matlab.ui.container.Menu
        PlayAllButton                  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        WindowFunctionTab              matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        plotWindowFunction             matlab.ui.control.UIAxes
        InstrumentsTab                 matlab.ui.container.Tab
        TonenumberLabel                matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MelodyLengthLabel              matlab.ui.control.Label
        MelodyToneCountEditField       matlab.ui.control.NumericEditField
        MelodyToneCountEditFieldLabel  matlab.ui.control.Label
        InstrumentsTable               matlab.ui.control.Table
        PianoTab                       matlab.ui.container.Tab
        PlayPianoButton                matlab.ui.control.Button
        PianoTable                     matlab.ui.control.Table
        GuitarTab                      matlab.ui.container.Tab
        PlayGuitarButton               matlab.ui.control.Button
        GuitarTable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        windowFunction WindowFunction % Description
        guitar Instruments % Description
        piano Instruments
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Windowfunctionsetup
            app.UITable.Data = zeros(5, 2);
            processingAmpVec   = [0.0  1.0  0.9  0.7  0.0];
            processingTimeVec  = [0.0  0.1  0.3  0.9  1.0];  
            app.UITable.Data = [0.0 0.0];
            app.windowFunction = WindowFunction(processingAmpVec, processingTimeVec);
            [xVec, yVec] = app.windowFunction.calcWindowFunction(11025);
            plot(app.plotWindowFunction, xVec, yVec);
            for i = 2 : length(processingAmpVec)
                app.UITable.Data = [app.UITable.Data; processingTimeVec(i) processingAmpVec(i)]
            end
            % Set Editable
            ncols = size(app.UITable.Data,2);
            app.UITable.ColumnEditable = true(1,ncols); %all columns editable

            %% Guitartable setup
            app.guitar = Instruments("guitar");
            numberRows = length(app.guitar.toneNames);
            numberCols = 10;
            app.GuitarTable.RowName = app.guitar.toneNames;
            app.GuitarTable.ColumnName = 1 : numberCols;

            for i = 1 : numberRows
                for j = 1 : numberCols
                    app.GuitarTable.ColumnFormat(j) ={'logical'};
                    app.GuitarTable.Data(i, j) = false; 
                end
            end

            ncols = size(app.GuitarTable.Data,2);
            app.GuitarTable.ColumnEditable = true(1,ncols); %all columns editable

            %% Pianotable setup
            app.piano = Instruments("piano");
            numberRows = length(app.piano.toneNames);
            numberCols = 10;
            app.PianoTable.RowName = app.piano.toneNames;
            app.PianoTable.ColumnName = 1 : numberCols;

            for i = 1 : numberRows
                
                for j = 1 : numberCols
                    app.PianoTable.ColumnFormat(j) ={'logical'};
                    app.PianoTable.Data(i, j) = false; 
                end
            end

            ncols = size(app.PianoTable.Data,2);
            app.PianoTable.ColumnEditable = true(1,ncols); %all columns editable
            

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if (event.Indices(2) == 1)
                app.windowFunction.relTimeVec(event.Indices(1)) = event.NewData;
            else
                app.windowFunction.relAmpVec(event.Indices(1)) = event.NewData;
            end
            [xVec, yVec] = app.windowFunction.calcWindowFunction(11025);
            plot(app.plotWindowFunction, xVec, yVec);
        end

        % Cell edit callback: PianoTable
        function PianoTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell edit callback: GuitarTable
        function GuitarTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: PlayAllButton
        function PlayAllButtonPushed(app, event)
            
        end

        % Button pushed function: PlayGuitarButton
        function PlayGuitarButtonPushed(app, event)
            numberColumns = 10;
            numberRows = length(app.guitar.toneNames);
            melodyTone = EmptyTone(11025);

            for i = 1 : numberColumns
                tones = [];
                for j = 1 : numberRows
                    if (app.GuitarTable.Data(j, i) == 1)
                        tones = [tones app.guitar.scale(j)];
                    end                    
                end

                    if(length(tones) > 1)
                        newTone = tones(1);
                        tones(1) = [];
                        newTone = newTone.interferSounds(tones);
                        melodyTone = melodyTone.concatTone(newTone);
                    else
                        melodyTone = melodyTone.concatTone(tones);
                   
                    end
            end
            melodyTone.play();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as';
            app.ExportmelodyMenu = uimenu(app.FileMenu);
            app.ExportmelodyMenu.Separator = 'on';
            app.ExportmelodyMenu.Text = 'Export melody';
            app.WAVMenu = uimenu(app.ExportmelodyMenu);
            app.WAVMenu.Text = 'WAV';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WindowFunctionTab = uitab(app.TabGroup);
            app.WindowFunctionTab.Title = 'Window Function';
            app.plotWindowFunction = uiaxes(app.WindowFunctionTab);
            title(app.plotWindowFunction, 'Relative Window Function')
            xlabel(app.plotWindowFunction, 'Realtive Time / %')
            ylabel(app.plotWindowFunction, 'Relative Amplitude / %')
            zlabel(app.plotWindowFunction, 'Z')
            app.UITable = uitable(app.WindowFunctionTab);
            app.UITable.ColumnName = {'Relative Time'; 'Amplitude'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.InstrumentsTab = uitab(app.TabGroup);
            app.InstrumentsTab.Title = 'Instruments';
            app.InstrumentsTable = uitable(app.InstrumentsTab);
            app.InstrumentsTable.ColumnName = {'T1'};
            app.InstrumentsTable.RowName = {'Duration, Piano,Guitar'};
            app.MelodyToneCountEditFieldLabel = uilabel(app.InstrumentsTab);
            app.MelodyToneCountEditFieldLabel.Text = 'Melody Tone Count';
            app.MelodyToneCountEditField = uieditfield(app.InstrumentsTab, 'numeric');
            app.MelodyLengthLabel = uilabel(app.InstrumentsTab);
            app.MelodyLengthLabel.Text = 'Melody Length';
            app.Label = uilabel(app.InstrumentsTab);
            app.Label.Text = '0';
            app.sLabel = uilabel(app.InstrumentsTab);
            app.sLabel.Text = 's';
            app.TonenumberLabel = uilabel(app.InstrumentsTab);
            app.TonenumberLabel.Text = 'Tone number ->';
            app.PianoTab = uitab(app.TabGroup);
            app.PianoTab.Title = 'Piano';
            app.PianoTable = uitable(app.PianoTab);
            app.PianoTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PianoTable.RowName = {};
            app.PianoTable.CellEditCallback = createCallbackFcn(app, @PianoTableCellEdit, true);
            app.PlayPianoButton = uibutton(app.PianoTab, 'push');
            app.PlayPianoButton.Text = 'Play Piano';
            app.GuitarTab = uitab(app.TabGroup);
            app.GuitarTab.Title = 'Guitar';
            app.GuitarTable = uitable(app.GuitarTab);
            app.GuitarTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.GuitarTable.RowName = {};
            app.GuitarTable.CellEditCallback = createCallbackFcn(app, @GuitarTableCellEdit, true);
            app.PlayGuitarButton = uibutton(app.GuitarTab, 'push');
            app.PlayGuitarButton.ButtonPushedFcn = createCallbackFcn(app, @PlayGuitarButtonPushed, true);
            app.PlayGuitarButton.Text = 'Play Guitar';
            app.PlayAllButton = uibutton(app.UIFigure, 'push');
            app.PlayAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAllButtonPushed, true);
            app.PlayAllButton.Text = 'Play All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end